classdef GUIRevision2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        DisplayValuesButton             matlab.ui.control.Button
        ElementForcesStressTable        matlab.ui.control.Table
        DisplacementNodalTable          matlab.ui.control.Table
        InsertFileNameButton            matlab.ui.control.Button
        InsertFileNameEditField         matlab.ui.control.EditField
        ForcesPanel3D                   matlab.ui.container.Panel
        MagnitudeEditField              matlab.ui.control.NumericEditField
        MagnitudeEditField_2Label       matlab.ui.control.Label
        ApplyButton_3                   matlab.ui.control.Button
        DirectionDropDown               matlab.ui.control.DropDown
        DirectionDropDown_2Label        matlab.ui.control.Label
        NodeNumberEditFieldForces       matlab.ui.control.NumericEditField
        NodeNumberEditField_5Label      matlab.ui.control.Label
        NodesPanel3D                    matlab.ui.container.Panel
        ZCoordinateEditField            matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel       matlab.ui.control.Label
        GenerateNodeButton              matlab.ui.control.Button
        NodeNumberEditFieldNodes        matlab.ui.control.NumericEditField
        NodeNumberEditField_4Label      matlab.ui.control.Label
        YCoordinateEditField            matlab.ui.control.NumericEditField
        YCoordinateEditField_2Label     matlab.ui.control.Label
        XCoordinateEditField            matlab.ui.control.NumericEditField
        XCoordinateEditField_2Label     matlab.ui.control.Label
        DisplayTable                    matlab.ui.control.Table
        SolveButton                     matlab.ui.control.Button
        DzeroPanel                      matlab.ui.container.Panel
        DzeroEditField                  matlab.ui.control.NumericEditField
        DzeroEditFieldLabel             matlab.ui.control.Label
        ApplyButton_2                   matlab.ui.control.Button
        TrussInformationPanel           matlab.ui.container.Panel
        NDUEditField                    matlab.ui.control.NumericEditField
        NDUEditFieldLabel               matlab.ui.control.Label
        SaveInformationButton           matlab.ui.control.Button
        YoungsModulusEditField          matlab.ui.control.NumericEditField
        YoungsModulusEditFieldLabel     matlab.ui.control.Label
        NumberofNodesEditField          matlab.ui.control.NumericEditField
        NumberofNodesEditFieldLabel     matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.NumericEditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        ElementsPanel                   matlab.ui.container.Panel
        EndNode2EditField               matlab.ui.control.NumericEditField
        EndNode2EditFieldLabel          matlab.ui.control.Label
        AreaOfElementEditField          matlab.ui.control.NumericEditField
        AreaOfElementEditFieldLabel     matlab.ui.control.Label
        EndNode1EditField               matlab.ui.control.NumericEditField
        EndNode1EditFieldLabel          matlab.ui.control.Label
        ElementNumberEditField          matlab.ui.control.NumericEditField
        ElementNumberEditFieldLabel     matlab.ui.control.Label
        GenerateElementButton           matlab.ui.control.Button
        ThreeDButton                    matlab.ui.control.Button
        TwoDButton                      matlab.ui.control.Button
        UploadDataButton                matlab.ui.control.Button
        CustomPointsButton              matlab.ui.control.Button
        Truss                           matlab.ui.control.UIAxes
        DeformedStructure               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property; % Description
        filename;
        data;
        PD;
        n_elements;
        n_nodes;
        E;
        NDU;
        X;
        Y;
        Z;
        node_number;
        ncon1;
        ncon2;
        element_number;
        A;
        direc;
        F;
        BC;
        magnitude;
        counter;
        input;
        dzero;
        ncon;
        K;
        d;
        i;
        u1;
        u2;
        v1;
        v2;
        Xnew;
        Ynew;
        TableRead;
        vars;
        FRarray;
        Sarray;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Start Up Function

            app.TrussInformationPanel.Visible = 'on'; %Truss Information Panel Turned Off at Start
            app.ForcesPanel3D.Visible = 'on';
            app.ElementsPanel.Visible = 'on'; %Elements Panel Turned Off at Start
            app.NodesPanel3D.Visible = 'on';
            app.DzeroPanel.Visible = 'on'; %Dzero Panel Turned Off at Start
            app.SolveButton.Visible = 'on';
            app.CustomPointsButton.BackgroundColor = 'y';
            app.UploadDataButton.BackgroundColor = 'y';
            app.counter = 1;

        end

        % Button pushed function: CustomPointsButton
        function CustomPointsButtonPushed(app, event)
            %Custom Points Button
            app.CustomPointsButton.BackgroundColor = 'g';
            app.UploadDataButton.BackgroundColor = [.96 .96 .96];
            app.TwoDButton.BackgroundColor = 'y';
            app.ThreeDButton.BackgroundColor = 'y';
      
        end

        % Button pushed function: TwoDButton
        function TwoDButtonPushed(app, event)
            %2D Button
            app.PD = 2;
            app.data(1,1) = app.PD;
            app.TwoDButton.BackgroundColor = 'g';
            app.ThreeDButton.BackgroundColor = [.96 .96 .96];
            app.TrussInformationPanel.BackgroundColor = 'y';


        end

        % Button pushed function: ThreeDButton
        function ThreeDButtonPushed(app, event)
            %3D Button
            app.PD = 3;
            app.data(1,1) = app.PD;
            app.ThreeDButton.BackgroundColor = 'g';
            app.TwoDButton.BackgroundColor = [.96 .96 .96];
            app.TrussInformationPanel.BackgroundColor = 'y';


        end

        % Button pushed function: SaveInformationButton
        function SaveInformationButtonPushed(app, event)
            %Save Information Button


            app.TrussInformationPanel.BackgroundColor = 'g';
            app.NodesPanel3D.BackgroundColor = 'y';
            %Retrieving Values From Text Fields
            app.n_nodes = app.NumberofNodesEditField.Value;
            app.n_elements = app.NumberofElementsEditField.Value;
            app.E = app.YoungsModulusEditField.Value;
            app.NDU = app.NDUEditField.Value;
            
            %Intializing data Matrix
            app.data = zeros((app.n_nodes*2),13);

            %Inserting Retireved Values into data Matrix 
            app.data(1,2) = app.n_elements;
            app.data(1,3) = app.n_nodes;
            app.data(1,9) = app.E;
            app.data(1,12) = app.NDU;

            %Inserting Data to DisplayTable
            app.DisplayTable.Data = cell(app.data(1,3)*2,12);
            app.DisplayTable.Data(1,1) = {app.PD};
            app.DisplayTable.Data(1,2) = {app.n_elements};
            app.DisplayTable.Data(1,3) = {app.n_nodes};
            app.DisplayTable.Data(1,9) = {app.E};
            app.DisplayTable.Data(1,12) = {app.NDU};

            %Reset Values to 0
            app.NumberofElementsEditField.Value = 0;
            app.NumberofNodesEditField.Value = 0;
            app.YoungsModulusEditField.Value = 0;
            app.NDUEditField.Value = 0;

        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
            %Generate Element Button
 
            %Retrieving Values from Panel
            app.element_number = app.ElementNumberEditField.Value;
            app.ncon1 = app.EndNode1EditField.Value;
            app.ncon2 = app.EndNode2EditField.Value;
            app.A = app.AreaOfElementEditField.Value;

            %Putting Element Information into data Matrix
            app.data(app.element_number,4) = app.ncon1;
            app.data(app.element_number,5) = app.ncon2;
            app.data(app.element_number,10) = app.A;

            %Display Elements on DisplayTable
            app.DisplayTable.Data(app.element_number,4) = {app.ncon1};
            app.DisplayTable.Data(app.element_number,5) = {app.ncon2};
            app.DisplayTable.Data(app.element_number,10) = {app.A};

            %Setting Fields Back to 0
            app.EndNode1EditField.Value = 0;
            app.EndNode2EditField.Value = 0;
            app.ElementNumberEditField.Value = 0;
            app.AreaOfElementEditField.Value = 0;

            %Background

                if app.element_number == app.n_elements
                    app.ElementsPanel.BackgroundColor = 'g';
                    app.ForcesPanel3D.BackgroundColor = 'y';
                    
                end
           app.ncon = [app.data(:,4),app.data(:,5)];
        end

        % Callback function
        function ApplyButtonPushed(app, event)
            %Force Panel Button

            %Setting Variables

            %Retrieving Variables From Fields
            app.node_number = app.NodeNumberEditFieldForces.Value;
            app.direc = app.DirectionDropDown.Value;
            app.magnitude = app.MagnitudeEditField.Value;

            %Putting Magnitude into F Array
                if(app.direc == 'X')
                    app.input = (app.node_number*2) - 1;
                    app.data(app.input,11) = app.magnitude;
                    app.DisplayTable.Data(app.input,11) = {app.magnitude}; 
                end
                if(app.direc == 'Y')
                    app.input = (app.node_number*2);
                    app.data(app.input,11) = app.magnitude;
                    app.DisplayTable.Data(app.input,11) = {app.magnitude};
                end
                if(app.direc =='Z')
                    app.input = (app.node_number*3);
                    app.data(app.input,11) = app.magnitude;
                    app.DisplayTable.Data(app.input,11) = {app.magnitude};
                end
                    
                
            %Setting Fields Back to 0    
            app.NodeNumberEditFieldForces.Value = 0;
            app.MagnitudeEditField.Value = 0;

            %Background
                if app.input == app.n_nodes*2
                    app.ForcesPanel3D.BackgroundColor = 'g';
                    app.DzeroPanel.BackgroundColor = 'y';
                end
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            %Dzero Button
           
            %Retrieving Dzero Value
            app.dzero = app.DzeroEditField.Value;

            %Putting Dzero Value into data Matrix
            app.data(app.counter,13) = app.dzero;
            app.DisplayTable.Data(app.counter,13) = {app.dzero};
            app.counter = app.counter + 1;
            

            %Setting Dzero Field Back to 0
            app.DzeroEditField.Value = 0;
            
            %Background
                if (app.counter-1) == app.NDU
                    app.DzeroPanel.BackgroundColor = 'g';
                    app.SolveButton.BackgroundColor = 'y';
                end

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)

       %Change Colour whenever Solve Button Pushed
        app.SolveButton.BackgroundColor = 'g';

        %2D Analysys
        app.DisplayValuesButton.BackgroundColor = 'y';
            if app.PD == 2
            app.PD = app.data(1,1);
            app.n_elements = app.data(1,2);
            app.n_nodes = app.data(1,3);
            app.E = app.data(1,9);
            app.A = app.data(:,10);
            app.ncon = [app.data(:,4),app.data(:,5)];
            app.X = app.data(:,6);
            app.Y = app.data(:,7);
            app.Z = app.data(:,8);
            app.NDU = app.data(1,12);
            app.dzero = app.data(:,13);
            app.F = app.data(:,11);
            app.FRarray = zeros(app.element_number*2,1);
            app.Sarray = zeros(app.element_number*2,1);
        
        %Plot the structure
                for i = 1:app.n_elements
                    n1 = app.ncon(i,1);
                    n2 = app.ncon(i,2);
                    x1 = app.X(n1);
                    x2 = app.X(n2);
                    y1 = app.Y(n1);
                    y2 = app.Y(n2);
                    plot(app.Truss,[x1,x2],[y1,y2],'LineWidth',10);
                    hold(app.Truss,'on');
                    plot(app.Truss,[x1, x2],[y1, y2],'ro');
                end

         %Initiate matrices
                KE = zeros(4);
                K = zeros(2*app.n_nodes);
                KT = zeros(2*app.n_nodes);
    
         %Pre-processing
                for i = 1:app.n_elements
                    n1 = app.ncon(i,1);
                    n2 = app.ncon(i,2);
                    x1 = app.X(n1);
                    x2 = app.X(n2);
                    y1 = app.Y(n1);
                    y2 = app.Y(n2);
    
                    if((x2 - x1)==0)
                        if(y2>y1)
                            alpha = pi/2;
                        else
                            alpha = -pi/2;
                        end
                    else
                        alpha = atan((y2 -y1)/(x2 - x1));
                    end
    
                    c = cos(alpha);
                    s = sin(alpha);
    
                    L = sqrt((x2 - x1)^2 + (y2 - y1)^2);
    
                    const = (app.A(i)*app.E)/L;
                    KE = [c*c c*s -c*c -c*s;c*s s*s -c*s -s*s;-c*c -c*s c*c c*s;-c*s -s*s c*s s*s];
    
                    KE = const*KE;
    
        %Assemble into overall stiffness matrix
    
                    j = n1;
                    k = n2;
    
                    II(1) = (2*j) - 1;
                    II(2) = (2*j);
                    II(3) = (2*k) - 1;
                    II(4) = (2*k);
    
    
                    for IX = 1:4
                        MI = II(IX);
                        for JX = 1:4
                            MJ = II(JX);
    
                            K(MI,MJ) = K(MI,MJ) + KE(IX,JX);
                        end
                    end
                end
    
                KT = K;
    
                for k = 1:app.NDU
                    n = app.dzero(k);
                    KT(n,:) = 0;
                end

                for k = 1:app.NDU
                    n = app.dzero(k);
                    KT(:,n) = 0;
                end

                for k = 1:app.NDU
                    n = app.dzero(k);
                    KT(n,n) = KT(n,n) + 1;
                end
    
        %Calculate Unknown displacements and reaction forces
    
                d = inv(KT)*app.F;
                F = K*d;
               
    
                for i = 1:app.n_elements
                    n1 = app.ncon(i,1);
                    n2 = app.ncon(i,2);
                    x1 = app.X(n1);
                    x2 = app.X(n2);
                    y1 = app.Y(n1);
                    y2 = app.Y(n2);
    
    
                    u1 = d(2*n1 - 1);
                    v1 = d(2*n1);
                    u2 = d(2*n2 - 1);
                    v2 = d(2*n2);
    
                    if((x2 - x1)==0)
                        if(y2>y1)
                            alpha = pi/2;
                        else
                            alpha = -pi/2;
                        end
                    else
                        alpha = atan((y2 -y1)/(x2 - x1));
                    end
    
                    c = cos(alpha);
                    s = sin(alpha);
                    L = sqrt((x2 - x1)^2 + (y2 - y1)^2);
    
                    
                    FR(i) = (app.A(i)*app.E/L)*(((u2 - u1)*c)+((v2 - v1)*s));
                    app.FRarray(i,1) = FR(i);

                    
                    Sigma(i) = FR(i)/app.A(i);
                    app.Sarray(i,1) = Sigma(i);
                end
    
    
         % Graphical presentation of Structural Deformation
    
         % Evaluate new nodal coordinates
                for i = 1 : app.n_nodes
                    u(i) = d(2*i - 1);
                    v(i) = d(2*i);
                    Xnew(i) = app.X(i) + 1000*u(i);
                    Ynew(i) = app.Y(i) + 1000*v(i);
                end

         % Generate output files
                    dlmwrite('Q1_Displacement.txt', d, '');
                    dlmwrite('Q1_Nodal_Force.txt', F, '');
                    dlmwrite('Q1_Ele_Force.txt', app.FRarray, '');
                    dlmwrite('Q1_Stress.txt', app.Sarray, '');
    
         % Display Distorted Structure
    
                for i = 1:app.n_elements
                    n1 = app.ncon(i,1);
                    n2 = app.ncon(i,2);
                    x1 = Xnew(n1);
                    x2 = Xnew(n2);
                    y1 = Ynew(n1);
                    y2 = Ynew(n2);
                    plot(app.DeformedStructure,[x1,x2],[y1,y2],'LineWidth',10);
                    hold(app.DeformedStructure,'on');
                    plot(app.DeformedStructure,[x1 x2],[y1 y2],'ro');


                end
                    
            end

         % 3D Analysis
           if app.PD == 3
            app.PD = app.data(1,1);
            app.n_elements = app.data(1,2);
            app.n_nodes = app.data(1,3);
            app.E = app.data(1,9);
            app.A = app.data(:,10);
            app.ncon = [app.data(:,4),app.data(:,5)];
            app.X = app.data(:,6);
            app.Y = app.data(:,7);
            app.Z = app.data(:,8);
            app.NDU = app.data(1,12);
            app.dzero = app.data(:,13);
            app.F = app.data(:,11);
            app.FRarray = zeros(app.element_number*2,1);
            app.Sarray = zeros(app.element_number*2,1);

        % Plot the element
        % Create a new figure for plotting
            for i = 1:app.n_elements
                n1 = app.ncon(i,1);
                n2 = app.ncon(i,2);
                x1 = app.X(n1);
                y1 = app.Y(n1);
                z1 = app.Z(n1);
                x2 = app.X(n2);
                y2 = app.Y(n2);
                z2 = app.Z(n2);

                plot3(app.Truss,[x1, x2], [y1, y2], [z1, z2], 'LineWidth', 2); % 3D plot
                hold(app.Truss,"on");
                plot3(app.Truss,[x1, x2], [y1, y2], [z1, z2], 'ro'); % 3D plot
                hold(app.Truss,"on");
            end


            % % Initialize matrices
            KE = zeros(6);
            K = zeros(3 * app.n_nodes);
            KT = zeros(3 * app.n_nodes);

            % Pre-processing
            for i = 1:app.n_elements
            % Evaluate elemental stiffness matrices
                n1 = app.ncon(i,1);
                n2 = app.ncon(i,2);
                x1 = app.X(n1);
                y1 = app.Y(n1);
                z1 = app.Z(n1);
                x2 = app.X(n2);
                y2 = app.Y(n2);
                z2 = app.Z(n2);

                L = sqrt((x2 - x1)^2 + (y2 - y1)^2 + (z2 - z1)^2);
                c = (x2 - x1) / L;
                s = (y2 - y1) / L;
                t = (z2 - z1) / L;

                const = (app.A(i) * app.E) / L;
                KE = const * [c^2, c * s, c * t, -c^2, -c * s, -c * t;
                    c * s, s^2, s * t, -c * s, -s^2, -s * t;
                    c * t, s * t, t^2, -c * t, -s * t, -t^2;
                    -c^2, -c * s, -c * t, c^2, c * s, c * t;
                    -c * s, -s^2, -s * t, c * s, s^2, s * t;
                    -c * t, -s * t, -t^2, c * t, s * t, t^2];

           % Assemble into overall stiffness matrix
                I = [3 * n1 - 2, 3 * n1 - 1, 3 * n1, 3 * n2 - 2, 3 * n2 - 1, 3 * n2];
                for ix = 1:6
                    for jx = 1:6
                        K(I(ix), I(jx)) = K(I(ix), I(jx)) + KE(ix, jx);
                    end
                end
            end

            % Develop modified K matrix: KT for calculations
            KT = K;
            for k = 1:app.NDU
                n = app.dzero(k);
                KT(n, :) = 0;
            end

            for k = 1:app.NDU
                n = app.dzero(k);
                KT(:, n) = 0;
            end

            for k = 1:app.NDU
                n = app.dzero(k);
                KT(n, n) = KT(n, n) + 1;
            end

            % Calculate unknown displacements and reaction forces
            d = inv(KT)*app.F;
            F = K * d;

            % Post-processing
            for i = 1:app.n_elements
                n1 = app.ncon(i,1);
                n2 = app.ncon(i,2);
                x1 = app.X(n1);
                y1 = app.Y(n1);
                z1 = app.Z(n1);
                x2 = app.X(n2);
                y2 = app.Y(n2);
                z2 = app.Z(n2);

                u1 = d(3 * n1 - 2);
                v1 = d(3 * n1 - 1);
                w1 = d(3 * n1);
                u2 = d(3 * n2 - 2);
                v2 = d(3 * n2 - 1);
                w2 = d(3 * n2);

                L = sqrt((x2 - x1)^2 + (y2 - y1)^2 + (z2 - z1)^2);

                % Axial force
                FR(i) = (app.A(i) * app.E / L) * ((u2 - u1) * (x2 - x1) + (v2 - v1) * (y2 - y1) + (w2 - w1) * (z2 - z1));
                app.FRarray(i,1) = FR(i);
                % Stress
                Sigma(i) = FR(i) / app.A(i);
                app.Sarray(i,1) = Sigma(i);
            end

            % Generate output files
            dlmwrite('Q1_Displacement.txt', d, '');
            dlmwrite('Q1_Nodal_Force.txt', F, '');
            dlmwrite('Q1_Ele_Force.txt', app.FRarray, '');
            dlmwrite('Q1_Stress.txt', app.Sarray, '');

            % Graphical presentation of structural deformation
            % Evaluate new nodal coordinates
            for i = 1:app.n_nodes
                u(i) = d(3 * i-2);
                v(i) = d(3 * i-1);
                w(i) = d(3 * i);
                Xnew(i) = app.X(i)+1000*u(i);
                Ynew(i) = app.Y(i)+1000*v(i);
                Znew(i) = app.Z(i)+1000*w(i);
            end

            % Display distorted structure superimposed on original structure
            figure;
            for i = 1:app.n_elements
                n1 = app.ncon(i,1);
                n2 = app.ncon(i,2);

                x1= Xnew(n1);
                x2= Xnew(n2);
                y1= Ynew(n1);
                y2= Ynew(n2);
                z1= Znew(n1);
                z2= Znew(n2);

                plot3(app.DeformedStructure,[x1,x2],[y1,y2],[z1,z2],'LineWidth',2);
                hold(app.DeformedStructure,"on");
                plot3(app.DeformedStructure,[x1 x2],[y1 y2],[z1,z2],'ro');
                hold(app.DeformedStructure,"on");
            end
      end
     
        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            app.UploadDataButton.BackgroundColor = 'g';
            app.CustomPointsButton.BackgroundColor = [.96 .96 .96];
            app.InsertFileNameButton.BackgroundColor = 'y';
            
        end

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
            %Generate Node Button
            
            %Setting Variables
            app.node_number = app.NodeNumberEditFieldNodes.Value;

            %Retrieving X and Y Coordinates of Nodes
            app.X = app.XCoordinateEditField.Value;
            app.Y = app.YCoordinateEditField.Value;
            app.Z = app.ZCoordinateEditField.Value;

            %Putting in X and Y Coordinates of Nodes into data Matrix
            app.data(app.node_number,6) = app.X;
            app.data(app.node_number,7) = app.Y;
            app.data(app.node_number,8) = app.Z;

            %Display Node Coordinates in Display Table
            app.DisplayTable.Data(app.node_number,6) = {app.X};
            app.DisplayTable.Data(app.node_number,7) = {app.Y};
            app.DisplayTable.Data(app.node_number,8) = {app.Z};

            %Setting Fields Back to 0
            app.XCoordinateEditField.Value = 0;
            app.YCoordinateEditField.Value = 0;
            app.ZCoordinateEditField.Value = 0;
            app.NodeNumberEditFieldNodes.Value = 0;

            %Background

                if app.node_number == app.n_nodes
                    app.NodesPanel3D.BackgroundColor = 'g';
                    app.ElementsPanel.BackgroundColor = 'y';
                end
                    
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            %Force Panel Button

            %Setting Variables

            %Retrieving Variables From Fields
            app.node_number = app.NodeNumberEditFieldForces.Value;
            app.direc = app.DirectionDropDown.Value;
            app.magnitude = app.MagnitudeEditField.Value;

            %Putting Magnitude into F Array
                if(app.direc == 'X')
                    app.input = (app.node_number*2) - 1;
                    app.data(app.input,11) = app.magnitude;
                    app.DisplayTable.Data(app.input,10) = {app.magnitude}; 
                end
                if(app.direc == 'Y')
                    app.input = (app.node_number*2);
                    app.data(app.input,11) = app.magnitude;
                    app.DisplayTable.Data(app.input,10) = {app.magnitude};
                end
                if(app.direc =='Z')
                    app.input = (app.node_number*3);
                    app.data(app.input,11) = app.magnitude;
                    app.DisplayTable.Data(app.input,10) = {app.magnitude};
                end
                    
                
            %Setting Fields Back to 0    
            app.NodeNumberEditFieldForces.Value = 0;
            app.MagnitudeEditField.Value = 0;

            %Background
                if app.input == app.n_nodes*2
                    app.ForcesPanel3D.BackgroundColor = 'g';
                    app.DzeroPanel.BackgroundColor = 'y';
                end
        end

        % Button pushed function: InsertFileNameButton
        function InsertFileNameButtonPushed(app, event)
            app.filename = app.InsertFileNameEditField.Value;
            app.InsertFileNameButton.BackgroundColor = 'g';
            [num,txt,raw] = xlsread(app.filename);
            app.data = num;
            app.PD = app.data(1,1);
            app.n_elements = app.data(1,2);
            app.n_nodes = app.data(1,3);
            app.E = app.data(1,9);
            app.A = app.data(:,10);
            app.ncon1 = num2cell(app.data(:,4));
            app.ncon2 = app.data(:,5);
            app.ncon = [app.data(:,4),app.data(:,5)];
            app.X = app.data(:,6);
            app.Y = app.data(:,7);
            app.Z = app.data(:,8);
            app.NDU = app.data(1,12);
            app.dzero = app.data(:,13);
            app.F = app.data(:,11);
            
            app.TableRead = readtable(app.filename);
            app.DisplayTable.Data= app.TableRead;


            if app.PD == 2
                app.TwoDButton.BackgroundColor = 'g';
                app.SolveButton.BackgroundColor = 'y';
            end

            if app.PD == 3
                app.ThreeDButton.BackgroundColor = 'g';
                app.SolveButton.BackgroundColor = 'y'; 
            end

        end

        % Button pushed function: DisplayValuesButton
        function DisplayValuesButtonPushed(app, event)
            app.DisplayValuesButton.BackgroundColor = 'g';
                    app.TableRead = importdata('Q1_Displacement.txt') ; 
                    app.DisplacementNodalTable.Data(:,1) = app.TableRead;
                    app.TableRead = importdata('Q1_Nodal_Force.txt') ; 
                    app.DisplacementNodalTable.Data(:,2) = app.TableRead;
                    app.TableRead = importdata('Q1_Ele_Force.txt') ; 
                    app.ElementForcesStressTable.Data(:,1) = app.TableRead;
                    app.TableRead = importdata('Q1_Stress.txt') ; 
                    app.ElementForcesStressTable.Data(:,2) = app.TableRead;
        end

        % Callback function
        function ResetButtonPushed(app, event)
            startupFcn(app)
            app.data = zeros((app.n_nodes*2),13);
            set(app.DisplayTable, 'Data', {});
            set(app.ElementForcesStressTable, 'Data', {});
            set(app.DisplacementNodalTable, 'Data', {});
            cla(app.Truss);
            cla(app.DeformedStructure);
            app.InsertFileNameButton.BackgroundColor = [.96 .96 .96];
            app.TwoDButton.BackgroundColor = [.96 .96 .96];
            app.ThreeDButton.BackgroundColor = [.96 .96 .96];
            app.TrussInformationPanel.BackgroundColor = [.96 .96 .96];
            app.NodesPanel3D.BackgroundColor = [.96 .96 .96];
            app.ElementsPanel.BackgroundColor = [.96 .96 .96];
            app.ForcesPanel3D.BackgroundColor = [.96 .96 .96];
            app.DzeroPanel.BackgroundColor = [.96 .96 .96];
            app.SolveButton.BackgroundColor = [.96 .96 .96];
            app.DisplayValuesButton.BackgroundColor = [.96 .96 .96];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DeformedStructure = uiaxes(app.GridLayout);
            title(app.DeformedStructure, 'Deformed Structure')
            xlabel(app.DeformedStructure, 'X')
            ylabel(app.DeformedStructure, 'Y')
            zlabel(app.DeformedStructure, 'Z')
            app.Truss = uiaxes(app.GridLayout);
            title(app.Truss, 'Truss')
            xlabel(app.Truss, 'X')
            ylabel(app.Truss, 'Y')
            zlabel(app.Truss, 'Z')
            app.CustomPointsButton = uibutton(app.GridLayout, 'push');
            app.CustomPointsButton.ButtonPushedFcn = createCallbackFcn(app, @CustomPointsButtonPushed, true);
            app.CustomPointsButton.Text = 'Custom Points';
            app.UploadDataButton = uibutton(app.GridLayout, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Text = 'Upload Data';
            app.TwoDButton = uibutton(app.GridLayout, 'push');
            app.TwoDButton.ButtonPushedFcn = createCallbackFcn(app, @TwoDButtonPushed, true);
            app.TwoDButton.Text = '2D';
            app.ThreeDButton = uibutton(app.GridLayout, 'push');
            app.ThreeDButton.ButtonPushedFcn = createCallbackFcn(app, @ThreeDButtonPushed, true);
            app.ThreeDButton.Text = '3D';
            app.ElementsPanel = uipanel(app.GridLayout);
            app.ElementsPanel.Title = 'Elements';
            app.GenerateElementButton = uibutton(app.ElementsPanel, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = 'Generate Element';
            app.ElementNumberEditFieldLabel = uilabel(app.ElementsPanel);
            app.ElementNumberEditFieldLabel.Text = 'Element Number';
            app.ElementNumberEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.EndNode1EditFieldLabel = uilabel(app.ElementsPanel);
            app.EndNode1EditFieldLabel.Text = 'End Node 1';
            app.EndNode1EditField = uieditfield(app.ElementsPanel, 'numeric');
            app.AreaOfElementEditFieldLabel = uilabel(app.ElementsPanel);
            app.AreaOfElementEditFieldLabel.Text = 'Area Of Element';
            app.AreaOfElementEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.EndNode2EditFieldLabel = uilabel(app.ElementsPanel);
            app.EndNode2EditFieldLabel.Text = 'End Node 2';
            app.EndNode2EditField = uieditfield(app.ElementsPanel, 'numeric');
            app.TrussInformationPanel = uipanel(app.GridLayout);
            app.TrussInformationPanel.Title = 'Truss Information';
            app.NumberofElementsEditFieldLabel = uilabel(app.TrussInformationPanel);
            app.NumberofElementsEditFieldLabel.Text = 'Number of Elements';
            app.NumberofElementsEditField = uieditfield(app.TrussInformationPanel, 'numeric');
            app.NumberofNodesEditFieldLabel = uilabel(app.TrussInformationPanel);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.TrussInformationPanel, 'numeric');
            app.YoungsModulusEditFieldLabel = uilabel(app.TrussInformationPanel);
            app.YoungsModulusEditFieldLabel.Text = 'Youngs Modulus';
            app.YoungsModulusEditField = uieditfield(app.TrussInformationPanel, 'numeric');
            app.SaveInformationButton = uibutton(app.TrussInformationPanel, 'push');
            app.SaveInformationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInformationButtonPushed, true);
            app.SaveInformationButton.Text = 'Save Information';
            app.NDUEditFieldLabel = uilabel(app.TrussInformationPanel);
            app.NDUEditFieldLabel.Text = 'NDU';
            app.NDUEditField = uieditfield(app.TrussInformationPanel, 'numeric');
            app.DzeroPanel = uipanel(app.GridLayout);
            app.DzeroPanel.Title = 'Dzero';
            app.ApplyButton_2 = uibutton(app.DzeroPanel, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.DzeroEditFieldLabel = uilabel(app.DzeroPanel);
            app.DzeroEditFieldLabel.Text = 'Dzero';
            app.DzeroEditField = uieditfield(app.DzeroPanel, 'numeric');
            app.SolveButton = uibutton(app.GridLayout, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.DisplayTable = uitable(app.GridLayout);
            app.DisplayTable.ColumnName = {'Dimensions'; 'Number of Elements'; 'Number of Nodes'; 'Ncon1'; 'Ncon2'; 'X coordinate'; 'Y coordinate'; 'Z Coordinate'; 'Young''s Modulus'; 'Area'; 'Force'; 'NDU'; 'Dzero'};
            app.DisplayTable.RowName = {};
            app.NodesPanel3D = uipanel(app.GridLayout);
            app.NodesPanel3D.Title = 'Nodes';
            app.XCoordinateEditField_2Label = uilabel(app.NodesPanel3D);
            app.XCoordinateEditField_2Label.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.NodesPanel3D, 'numeric');
            app.YCoordinateEditField_2Label = uilabel(app.NodesPanel3D);
            app.YCoordinateEditField_2Label.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.NodesPanel3D, 'numeric');
            app.NodeNumberEditField_4Label = uilabel(app.NodesPanel3D);
            app.NodeNumberEditField_4Label.Text = 'Node Number';
            app.NodeNumberEditFieldNodes = uieditfield(app.NodesPanel3D, 'numeric');
            app.GenerateNodeButton = uibutton(app.NodesPanel3D, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = 'Generate Node';
            app.ZCoordinateEditFieldLabel = uilabel(app.NodesPanel3D);
            app.ZCoordinateEditFieldLabel.Text = 'Z Coordinate';
            app.ZCoordinateEditField = uieditfield(app.NodesPanel3D, 'numeric');
            app.ForcesPanel3D = uipanel(app.GridLayout);
            app.ForcesPanel3D.Title = 'Forces';
            app.NodeNumberEditField_5Label = uilabel(app.ForcesPanel3D);
            app.NodeNumberEditField_5Label.Text = 'Node Number';
            app.NodeNumberEditFieldForces = uieditfield(app.ForcesPanel3D, 'numeric');
            app.DirectionDropDown_2Label = uilabel(app.ForcesPanel3D);
            app.DirectionDropDown_2Label.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.ForcesPanel3D);
            app.DirectionDropDown.Items = {'Choose Direction', 'X', 'Y', 'Z'};
            app.DirectionDropDown.Value = 'Choose Direction';
            app.ApplyButton_3 = uibutton(app.ForcesPanel3D, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.MagnitudeEditField_2Label = uilabel(app.ForcesPanel3D);
            app.MagnitudeEditField_2Label.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.ForcesPanel3D, 'numeric');
            app.InsertFileNameEditField = uieditfield(app.GridLayout, 'text');
            app.InsertFileNameButton = uibutton(app.GridLayout, 'push');
            app.InsertFileNameButton.ButtonPushedFcn = createCallbackFcn(app, @InsertFileNameButtonPushed, true);
            app.InsertFileNameButton.Text = 'Insert File Name';
            app.DisplacementNodalTable = uitable(app.GridLayout);
            app.DisplacementNodalTable.ColumnName = {'Displacement'; 'Nodal Forces'};
            app.DisplacementNodalTable.RowName = {};
            app.ElementForcesStressTable = uitable(app.GridLayout);
            app.ElementForcesStressTable.ColumnName = {'Element Forces'; 'Stress'};
            app.ElementForcesStressTable.RowName = {};
            app.DisplayValuesButton = uibutton(app.GridLayout, 'push');
            app.DisplayValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayValuesButtonPushed, true);
            app.DisplayValuesButton.Text = 'Display Values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIRevision2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end